classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Imagine                        matlab.ui.container.Tab
        TakeImageButton                matlab.ui.control.Button
        TransmitImageButton            matlab.ui.control.Button
        Telemetry_Status_3             matlab.ui.control.Label
        StatusLabel_3                  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Rotation                       matlab.ui.container.Tab
        SelectDirectionDropDownLabel   matlab.ui.control.Label
        SelectDirectionDropDown        matlab.ui.control.DropDown
        ButtonGroup                    matlab.ui.container.ButtonGroup
        ClockwiseButton                matlab.ui.control.ToggleButton
        CounterClockwiseButton         matlab.ui.control.ToggleButton
        SpeedEditFieldLabel            matlab.ui.control.Label
        SpeedEditField                 matlab.ui.control.EditField
        StatusLabel_2                  matlab.ui.control.Label
        Telemetry_Status_2             matlab.ui.control.Label
        COMMANDButton                  matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        CodeLabel                      matlab.ui.control.Label
        Telemetry                      matlab.ui.container.Tab
        SelectRequiredTelemetryButtonGroup  matlab.ui.container.ButtonGroup
        GyroscopeButton                matlab.ui.control.ToggleButton
        AccelerationButton             matlab.ui.control.ToggleButton
        TemperatureButton              matlab.ui.control.ToggleButton
        AllTelemetryButton             matlab.ui.control.ToggleButton
        XRotationLabel                 matlab.ui.control.Label
        YRotationLabel                 matlab.ui.control.Label
        ZRotationLabel                 matlab.ui.control.Label
        XAccelerationLabel             matlab.ui.control.Label
        YAccelerationLabel             matlab.ui.control.Label
        ZAccelerationLabel             matlab.ui.control.Label
        TemperatureCLabel              matlab.ui.control.Label
        GyroscopeTelemetryradsecLabel  matlab.ui.control.Label
        AccelerationTelemetrymsLabel   matlab.ui.control.Label
        X_Rotation                     matlab.ui.control.Label
        Y_Rotation                     matlab.ui.control.Label
        Z_Rotation                     matlab.ui.control.Label
        X_Acceleration                 matlab.ui.control.Label
        Y_Acceleration                 matlab.ui.control.Label
        Z_Acceleration                 matlab.ui.control.Label
        Temperature                    matlab.ui.control.Label
        StatusLabel                    matlab.ui.control.Label
        Telemetry_Status               matlab.ui.control.Label
        COMMANDButton_2                matlab.ui.control.Button
        CodeLabel_2                    matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        GROUNDSTATIONGRAPHICALUSERINTERFACELabel  matlab.ui.control.Label
    end


    methods (Access = private)
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: COMMANDButton
        function COMMANDButtonPushed(app, event)
%          Code=append("OXOA","OXOB");
          n1=1;
          n2=3;
          n4=6;
          n5=2;
            if (app.ClockwiseButton.Value==1)
%                app.Label.Text="0X010X0A";
               n3=7;
            elseif (app.CounterClockwiseButton.Value==1)
%                app.Label.Text="0X010X0B";
               n3=8;
            end
         Pack=pk(n1,n2,n3,n4,n5);
         app.Label.Text=join(Pack,'');
        end

        % Button pushed function: COMMANDButton_2
        function COMMANDButton_2Pushed(app, event)
                  Data=["0x21" "00" "01" "02" "03" "04" "0A" "0B" "0C" "0D" "1C"];     

          n1=1;
          n2=5;
          n4=2;
          if (app.GyroscopeButton.Value==1)
%                app.Label_2.Text="0X030X0A";
          n3=7;  
          n5=9;  
          elseif (app.AccelerationButton.Value==1)
%                app.Label_2.Text="0X030X0B";
          n3=8;  
          n5=9;
          elseif (app.TemperatureButton.Value==1)
%                app.Label_2.Text="OXO3OXOC";
          n3=9;  
          n5=6;
         elseif (app.AllTelemetryButton.Value==1)
%                app.Label_2.Text="OXO3OXOD";
          n3=10;  
          n5=11;
          end   
          
         Pack=pk(n1,n2,n3,n4,n5);
         app.Label_2.Text=join(Pack,'');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Imagine = uitab(app.TabGroup);
            app.Imagine.Title = 'Imaging';
            app.TakeImageButton = uibutton(app.Imagine, 'push');
            app.TakeImageButton.Text = 'Take Image';
            app.TransmitImageButton = uibutton(app.Imagine, 'push');
            app.TransmitImageButton.Text = 'Transmit Image';
            app.Telemetry_Status_3 = uilabel(app.Imagine);
            app.Telemetry_Status_3.Text = '';
            app.StatusLabel_3 = uilabel(app.Imagine);
            app.StatusLabel_3.Text = 'Status:';
            app.Image = uiimage(app.Imagine);
            app.Rotation = uitab(app.TabGroup);
            app.Rotation.Title = 'Rotation';
            app.SelectDirectionDropDownLabel = uilabel(app.Rotation);
            app.SelectDirectionDropDownLabel.Text = {'Select Direction'; ''};
            app.SelectDirectionDropDown = uidropdown(app.Rotation);
            app.SelectDirectionDropDown.Items = {'X-Rotation', 'Y-Rotation', 'Z-Rotation'};
            app.SelectDirectionDropDown.Value = 'X-Rotation';
            app.ButtonGroup = uibuttongroup(app.Rotation);
            app.ClockwiseButton = uitogglebutton(app.ButtonGroup);
            app.ClockwiseButton.Text = 'Clockwise';
            app.ClockwiseButton.Value = true;
            app.CounterClockwiseButton = uitogglebutton(app.ButtonGroup);
            app.CounterClockwiseButton.Text = 'Counter-Clockwise';
            app.SpeedEditFieldLabel = uilabel(app.Rotation);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.Rotation, 'text');
            app.StatusLabel_2 = uilabel(app.Rotation);
            app.StatusLabel_2.Text = 'Status';
            app.Telemetry_Status_2 = uilabel(app.Rotation);
            app.Telemetry_Status_2.Text = '';
            app.COMMANDButton = uibutton(app.Rotation, 'push');
            app.COMMANDButton.ButtonPushedFcn = createCallbackFcn(app, @COMMANDButtonPushed, true);
            app.COMMANDButton.Text = 'COMMAND';
            app.Label = uilabel(app.Rotation);
            app.Label.Text = '';
            app.CodeLabel = uilabel(app.Rotation);
            app.CodeLabel.Text = 'Code';
            app.Telemetry = uitab(app.TabGroup);
            app.Telemetry.Title = 'Telemetry';
            app.SelectRequiredTelemetryButtonGroup = uibuttongroup(app.Telemetry);
            app.SelectRequiredTelemetryButtonGroup.Title = 'Select Required Telemetry';
            app.GyroscopeButton = uitogglebutton(app.SelectRequiredTelemetryButtonGroup);
            app.GyroscopeButton.Text = {'Gyroscope'; ''};
            app.GyroscopeButton.Value = true;
            app.AccelerationButton = uitogglebutton(app.SelectRequiredTelemetryButtonGroup);
            app.AccelerationButton.Text = 'Acceleration';
            app.TemperatureButton = uitogglebutton(app.SelectRequiredTelemetryButtonGroup);
            app.TemperatureButton.Text = 'Temperature';
            app.AllTelemetryButton = uitogglebutton(app.SelectRequiredTelemetryButtonGroup);
            app.AllTelemetryButton.Text = 'All Telemetry';
            app.XRotationLabel = uilabel(app.Telemetry);
            app.XRotationLabel.Text = 'X-Rotation:';
            app.YRotationLabel = uilabel(app.Telemetry);
            app.YRotationLabel.Text = 'Y-Rotation:';
            app.ZRotationLabel = uilabel(app.Telemetry);
            app.ZRotationLabel.Text = 'Z-Rotation:';
            app.XAccelerationLabel = uilabel(app.Telemetry);
            app.XAccelerationLabel.Text = 'X-Acceleration:';
            app.YAccelerationLabel = uilabel(app.Telemetry);
            app.YAccelerationLabel.Text = 'Y-Acceleration:';
            app.ZAccelerationLabel = uilabel(app.Telemetry);
            app.ZAccelerationLabel.Text = 'Z-Acceleration:';
            app.TemperatureCLabel = uilabel(app.Telemetry);
            app.TemperatureCLabel.Text = 'Temperature (°C):';
            app.GyroscopeTelemetryradsecLabel = uilabel(app.Telemetry);
            app.GyroscopeTelemetryradsecLabel.Text = 'Gyroscope Telemetry (rad/sec)';
            app.AccelerationTelemetrymsLabel = uilabel(app.Telemetry);
            app.AccelerationTelemetrymsLabel.Text = 'Acceleration Telemetry (m/s)';
            app.X_Rotation = uilabel(app.Telemetry);
            app.X_Rotation.Text = '';
            app.Y_Rotation = uilabel(app.Telemetry);
            app.Y_Rotation.Text = '';
            app.Z_Rotation = uilabel(app.Telemetry);
            app.Z_Rotation.Text = '';
            app.X_Acceleration = uilabel(app.Telemetry);
            app.X_Acceleration.Text = '';
            app.Y_Acceleration = uilabel(app.Telemetry);
            app.Y_Acceleration.Text = '';
            app.Z_Acceleration = uilabel(app.Telemetry);
            app.Z_Acceleration.Text = '';
            app.Temperature = uilabel(app.Telemetry);
            app.Temperature.Text = '';
            app.StatusLabel = uilabel(app.Telemetry);
            app.StatusLabel.Text = 'Status';
            app.Telemetry_Status = uilabel(app.Telemetry);
            app.Telemetry_Status.Text = '';
            app.COMMANDButton_2 = uibutton(app.Telemetry, 'push');
            app.COMMANDButton_2.ButtonPushedFcn = createCallbackFcn(app, @COMMANDButton_2Pushed, true);
            app.COMMANDButton_2.Text = 'COMMAND';
            app.CodeLabel_2 = uilabel(app.Telemetry);
            app.CodeLabel_2.Text = 'Code';
            app.Label_2 = uilabel(app.Telemetry);
            app.Label_2.Text = '';
            app.GROUNDSTATIONGRAPHICALUSERINTERFACELabel = uilabel(app.UIFigure);
            app.GROUNDSTATIONGRAPHICALUSERINTERFACELabel.Text = 'GROUND STATION GRAPHICAL USER INTERFACE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end