classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GroundStationAeroSpaceTeam17UIFigure  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Imagine                        matlab.ui.container.Tab
        OpenCameraButton               matlab.ui.control.Button
        CloseCameraButton              matlab.ui.control.Button
        Telemetry_Status_3             matlab.ui.control.Label
        StatusLabel_3                  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        CodeLabel_3                    matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Lamp_2                         matlab.ui.control.Lamp
        Rotation                       matlab.ui.container.Tab
        SelectDirectionDropDownLabel   matlab.ui.control.Label
        SelectDirectionDropDown        matlab.ui.control.DropDown
        StatusLabel_2                  matlab.ui.control.Label
        Telemetry_Status_2             matlab.ui.control.Label
        COMMANDButton                  matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        CodeLabel                      matlab.ui.control.Label
        SpeedSpinnerLabel              matlab.ui.control.Label
        SpeedSpinner                   matlab.ui.control.Spinner
        SpeedKnob                      matlab.ui.control.Knob
        Lamp                           matlab.ui.control.Lamp
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        ClockwiseButton                matlab.ui.control.RadioButton
        CounterClockwiseButton         matlab.ui.control.RadioButton
        AngleSpinnerLabel              matlab.ui.control.Label
        AngleSpinner                   matlab.ui.control.Spinner
        AngleKnob                      matlab.ui.control.Knob
        Telemetry                      matlab.ui.container.Tab
        XRotationLabel                 matlab.ui.control.Label
        YRotationLabel                 matlab.ui.control.Label
        ZRotationLabel                 matlab.ui.control.Label
        XAccelerationLabel             matlab.ui.control.Label
        YAccelerationLabel             matlab.ui.control.Label
        ZAccelerationLabel             matlab.ui.control.Label
        TemperatureCLabel              matlab.ui.control.Label
        GyroscopeTelemetryDegreeLabel  matlab.ui.control.Label
        AccelerationTelemetrymsLabel   matlab.ui.control.Label
        X_Rotation                     matlab.ui.control.Label
        Y_Rotation                     matlab.ui.control.Label
        Z_Rotation                     matlab.ui.control.Label
        X_Acceleration                 matlab.ui.control.Label
        Y_Acceleration                 matlab.ui.control.Label
        Z_Acceleration                 matlab.ui.control.Label
        Temperature                    matlab.ui.control.Label
        Telemetry_Status               matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        TemperatureGauge               matlab.ui.control.SemicircularGauge
        Lamp_3                         matlab.ui.control.Lamp
        StatusLabel_4                  matlab.ui.control.Label
        Telemetry_Status_4             matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        CodeLabel_4                    matlab.ui.control.Label
        SelectRequiredTelemetryButtonGroup_2  matlab.ui.container.ButtonGroup
        GyroscopeButton                matlab.ui.control.RadioButton
        AccelerationButton             matlab.ui.control.RadioButton
        TemperatureButton              matlab.ui.control.RadioButton
        AllTelemetryButton             matlab.ui.control.RadioButton
        COMMANDButton_2                matlab.ui.control.Button
        Gauge                          matlab.ui.control.Gauge
        Switch                         matlab.ui.control.Switch
        GROUNDSTATIONLabel             matlab.ui.control.Label
        PressF1toseehelpLabel          matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    methods (Access = public)
        
        % Packing Codes For ADCS
        function Pack=pk(~,n1,n2,n3,n4,n5)
            
            Data = ["0x21" "00" "01" "02" "03" "04" "0A" "0B" "0C" "0D" "1C"];
            Pack = [Data(n1) Data(n2) Data(n3) Data(n4) Data(n5)];
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: COMMANDButton
        function COMMANDButtonPushed(app, event)
            
            % Fixed Codes For Send Data
            
            n1 = 1;   % ID OF ADCS
            n2 = 3;   % Rotation
            n4 = 6;   % NMI for Temp
            n5 = 2;   % NMI or NMO for not sending data
            
            % Select ClockwiseButton or CounterClockwiseButton
            
            if (app.ClockwiseButton.Value==1)
                
                n3 = 7;
                Send_This = {'21' '01' '0A' '00' '04'} ;
                
            elseif (app.CounterClockwiseButton.Value==1)
                
                n3 = 8;
                
            end
            
            % Packing The codes for user inputs
            
            Pack=pk(app,n1,n2,n3,n4,n5);
            % assignin('base','Rot',join(Pack,''));
            app.Label.Text=join(Pack,'');
            
            % SEND THE CODE TO COM
            fwrite(COM,hex2dec(Send_This)) %Send Your Data.
            msgbox("Sending")
            % SCAN for Data Received by COM
            Recieved_Data = fscanf(COM,13) ;
            % Led Color
            if Recieved_Data ==1
                app.Lamp.Color = 'green';
            else
                app.Lamp.Color = 'red';
            end
        end

        % Callback function
        function COMMANDButton_2Pushed(app, event)
            
            % Fixed Codes For Received Data
            
            n1 = 1;   %ID
            n2 = 5;   %
            n4 = 2;   %
            
            % Select Which Data to receive
            
            if (app.GyroscopeButton.Value==1)
                n3 = 7;
                n5 = 9;
            elseif (app.AccelerationButton.Value==1)
                n3 = 8;
                n5 = 9;
            elseif (app.TemperatureButton.Value==1)
                n3 = 9;
                n5 = 6;
            elseif (app.AllTelemetryButton.Value==1)
                n3 = 10;
                n5 = 11;
            end
            
            % Packing The codes for user inputs
            Pack = pk(app,n1,n2,n3,n4,n5);
            assignin('base','TLM',join(Pack,''));
            app.Label_2.Text=join(Pack,'');
            
        end

        % Button pushed function: OpenCameraButton
        function OpenCameraButtonPushed(app, event)
            %            [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
            %            fullname = [filepath, filename];
            %            ImageFile = imread(fullname);
            %            imagesc(app.UIAxes, ImageFile);
            %            [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
            %            fullname = [filepath, filename];
            %            ImageFile = imread(fullname);
            %            imshow(ImageFile, 'Parent', app.UIAxes);
            n1=1; n2=4; n3=8; n4=3; n5=2;
            Pack=pk(app,n1,n2,n3,n4,n5);
            assignin('base','Takeimg',join(Pack,''));
            app.Label_3.Text=join(Pack,'');
            
        end

        % Button pushed function: CloseCameraButton
        function CloseCameraButtonPushed(app, event)
            [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
            fullname = [filepath, filename];
            ImageFile = imread(fullname);
            imshow(ImageFile, 'Parent', app.UIAxes);
            n1=1; n2=4; n3=7; n4=2; n5=2;
            Pack=pk(app,n1,n2,n3,n4,n5);
            assignin('base','sendimg',join(Pack,''));
            app.Label_3.Text=join(Pack,'');
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            
            
        end

        % Value changed function: SpeedKnob
        function SpeedKnobValueChanged(app, event)
            app.SpeedSpinner.Value = app.SpeedKnob.Value ;
            if app.SpeedKnob.Value ~= 0 || app.SpeedSpinner.Value ~= 0
                app.AngleKnob.Value = 0;
                app.AngleSpinner.Value = 0;
            end
            
        end

        % Value changed function: SpeedSpinner
        function SpeedSpinnerValueChanged(app, event)
            app.SpeedKnob.Value = app.SpeedSpinner.Value;
            
            if app.SpeedKnob.Value ~= 0 || app.SpeedSpinner.Value ~= 0
                app.AngleKnob.Value = 0;
                app.AngleSpinner.Value = 0;
            end
        end

        % Value changed function: SelectDirectionDropDown
        function SelectDirectionDropDownValueChanged(app, event)
            %  value = app.SelectDirectionDropDown.Value;
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            %    selectedTab = app.TabGroup.SelectedTab;
            % Clear all Labels for new comands
            app.Label.Text   ='';
            app.Label_2.Text ='';
            app.Label_3.Text ='';
            app.SpeedSpinner.Value = 0;
            app.SpeedKnob.Value = 0;
            app.AngleSpinner.Value = 0;
            app.AngleKnob.Value=0;
        end

        % Value changed function: AngleKnob
        function AngleKnobValueChanged(app, event)
            app.AngleSpinner.Value = app.AngleKnob.Value;
            if app.AngleKnob.Value ~=0 || app.AngleSpinner.Value ~= 0
                app.SpeedKnob.Value = 0;
                app.SpeedSpinner.Value = 0;
            end
        end

        % Value changed function: AngleSpinner
        function AngleSpinnerValueChanged(app, event)
            app.AngleKnob.Value = app.AngleSpinner.Value;
            if app.AngleKnob.Value ~=0 || app.AngleSpinner.Value ~= 0
                app.SpeedKnob.Value = 0;
                app.SpeedSpinner.Value = 0;
            end
        end

        % Key press function: GroundStationAeroSpaceTeam17UIFigure
        function GroundStationAeroSpaceTeam17UIFigureKeyPress(app, event)
            key = event.Key;
            if key == "f1"
                msgbox("This Is The Ground Station For The ADCS subsystem Created by Aerospace Team","Help") ;
            end
        end

        % Button pushed function: COMMANDButton_2
        function COMMANDButton_2Pushed2(app, event)
            hold(app.UIAxes2);
            plot(app.UIAxes2,[1 2 3 4],'-r');
            plot(app.UIAxes2,[10 9 4 7],'--b');
            app.TemperatureGauge.Value = 37;
            
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if app.Switch.Value == "On"
                
                delete(instrfindall);
                % insert your serial properties here
                COM = serial('COM11','BaudRate',9600);
                fopen(COM); %Open Port
                % fwrite(COM,'Connected')
                msgbox('Connected')
            elseif app.Switch.Value == "Off"
                
                delete(instrfindall);
                msgbox('Disconnected')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GroundStationAeroSpaceTeam17UIFigure = uifigure('Visible', 'off');
            app.GroundStationAeroSpaceTeam17UIFigure.Name = 'Ground Station - AeroSpace Team 17';
            app.GroundStationAeroSpaceTeam17UIFigure.KeyPressFcn = createCallbackFcn(app, @GroundStationAeroSpaceTeam17UIFigureKeyPress, true);
            app.TabGroup = uitabgroup(app.GroundStationAeroSpaceTeam17UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Imagine = uitab(app.TabGroup);
            app.Imagine.Title = 'Imaging';
            app.OpenCameraButton = uibutton(app.Imagine, 'push');
            app.OpenCameraButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCameraButtonPushed, true);
            app.OpenCameraButton.Text = 'Open Camera';
            app.CloseCameraButton = uibutton(app.Imagine, 'push');
            app.CloseCameraButton.ButtonPushedFcn = createCallbackFcn(app, @CloseCameraButtonPushed, true);
            app.CloseCameraButton.Text = 'Close Camera';
            app.Telemetry_Status_3 = uilabel(app.Imagine);
            app.Telemetry_Status_3.Text = '';
            app.StatusLabel_3 = uilabel(app.Imagine);
            app.StatusLabel_3.Text = 'Status';
            app.UIAxes = uiaxes(app.Imagine);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            app.CodeLabel_3 = uilabel(app.Imagine);
            app.CodeLabel_3.Text = 'Code';
            app.Label_3 = uilabel(app.Imagine);
            app.Label_3.Text = '';
            app.Lamp_2 = uilamp(app.Imagine);
            app.Rotation = uitab(app.TabGroup);
            app.Rotation.Title = 'Rotation';
            app.SelectDirectionDropDownLabel = uilabel(app.Rotation);
            app.SelectDirectionDropDownLabel.Text = {'Select Direction'; ''};
            app.SelectDirectionDropDown = uidropdown(app.Rotation);
            app.SelectDirectionDropDown.Items = {'X-Rotation', 'Y-Rotation', 'Z-Rotation'};
            app.SelectDirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDirectionDropDownValueChanged, true);
            app.SelectDirectionDropDown.Value = 'X-Rotation';
            app.StatusLabel_2 = uilabel(app.Rotation);
            app.StatusLabel_2.Text = 'Status';
            app.Telemetry_Status_2 = uilabel(app.Rotation);
            app.Telemetry_Status_2.Text = '';
            app.COMMANDButton = uibutton(app.Rotation, 'push');
            app.COMMANDButton.ButtonPushedFcn = createCallbackFcn(app, @COMMANDButtonPushed, true);
            app.COMMANDButton.Text = 'COMMAND';
            app.Label = uilabel(app.Rotation);
            app.Label.Text = '';
            app.CodeLabel = uilabel(app.Rotation);
            app.CodeLabel.Text = 'Code';
            app.SpeedSpinnerLabel = uilabel(app.Rotation);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.Rotation);
            app.SpeedSpinner.ValueChangedFcn = createCallbackFcn(app, @SpeedSpinnerValueChanged, true);
            app.SpeedKnob = uiknob(app.Rotation, 'continuous');
            app.SpeedKnob.ValueChangedFcn = createCallbackFcn(app, @SpeedKnobValueChanged, true);
            app.Lamp = uilamp(app.Rotation);
            app.ButtonGroup_2 = uibuttongroup(app.Rotation);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ClockwiseButton = uiradiobutton(app.ButtonGroup_2);
            app.ClockwiseButton.Text = 'Clockwise';
            app.ClockwiseButton.Value = true;
            app.CounterClockwiseButton = uiradiobutton(app.ButtonGroup_2);
            app.CounterClockwiseButton.Text = 'Counter-Clockwise';
            app.AngleSpinnerLabel = uilabel(app.Rotation);
            app.AngleSpinnerLabel.Text = 'Angle';
            app.AngleSpinner = uispinner(app.Rotation);
            app.AngleSpinner.ValueChangedFcn = createCallbackFcn(app, @AngleSpinnerValueChanged, true);
            app.AngleKnob = uiknob(app.Rotation, 'continuous');
            app.AngleKnob.Limits = [0 360];
            app.AngleKnob.MajorTicks = [0 30 60 90 120 150 180 210 240 270 300 330 360];
            app.AngleKnob.ValueChangedFcn = createCallbackFcn(app, @AngleKnobValueChanged, true);
            app.Telemetry = uitab(app.TabGroup);
            app.Telemetry.Title = 'Telemetry';
            app.XRotationLabel = uilabel(app.Telemetry);
            app.XRotationLabel.Text = 'X-Rotation:';
            app.YRotationLabel = uilabel(app.Telemetry);
            app.YRotationLabel.Text = 'Y-Rotation:';
            app.ZRotationLabel = uilabel(app.Telemetry);
            app.ZRotationLabel.Text = 'Z-Rotation:';
            app.XAccelerationLabel = uilabel(app.Telemetry);
            app.XAccelerationLabel.Text = 'X-Acceleration:';
            app.YAccelerationLabel = uilabel(app.Telemetry);
            app.YAccelerationLabel.Text = 'Y-Acceleration:';
            app.ZAccelerationLabel = uilabel(app.Telemetry);
            app.ZAccelerationLabel.Text = 'Z-Acceleration:';
            app.TemperatureCLabel = uilabel(app.Telemetry);
            app.TemperatureCLabel.Text = 'Temperature (°C):';
            app.GyroscopeTelemetryDegreeLabel = uilabel(app.Telemetry);
            app.GyroscopeTelemetryDegreeLabel.Text = 'Gyroscope Telemetry (Degree)';
            app.AccelerationTelemetrymsLabel = uilabel(app.Telemetry);
            app.AccelerationTelemetrymsLabel.Text = 'Acceleration Telemetry (m/s)';
            app.X_Rotation = uilabel(app.Telemetry);
            app.X_Rotation.Text = '';
            app.Y_Rotation = uilabel(app.Telemetry);
            app.Y_Rotation.Text = '';
            app.Z_Rotation = uilabel(app.Telemetry);
            app.Z_Rotation.Text = '';
            app.X_Acceleration = uilabel(app.Telemetry);
            app.X_Acceleration.Text = '';
            app.Y_Acceleration = uilabel(app.Telemetry);
            app.Y_Acceleration.Text = '';
            app.Z_Acceleration = uilabel(app.Telemetry);
            app.Z_Acceleration.Text = '';
            app.Temperature = uilabel(app.Telemetry);
            app.Temperature.Text = '';
            app.Telemetry_Status = uilabel(app.Telemetry);
            app.Telemetry_Status.Text = '';
            app.Label_2 = uilabel(app.Telemetry);
            app.Label_2.Text = '';
            app.UIAxes2 = uiaxes(app.Telemetry);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.CLim = [0 10];
            app.UIAxes2.ALim = [0 10];
            app.TemperatureGauge = uigauge(app.Telemetry, 'semicircular');
            app.TemperatureGauge.Limits = [0 50];
            app.Lamp_3 = uilamp(app.Telemetry);
            app.StatusLabel_4 = uilabel(app.Telemetry);
            app.StatusLabel_4.Text = 'Status';
            app.Telemetry_Status_4 = uilabel(app.Telemetry);
            app.Telemetry_Status_4.Text = '';
            app.Label_4 = uilabel(app.Telemetry);
            app.Label_4.Text = '';
            app.CodeLabel_4 = uilabel(app.Telemetry);
            app.CodeLabel_4.Text = 'Code';
            app.SelectRequiredTelemetryButtonGroup_2 = uibuttongroup(app.Telemetry);
            app.SelectRequiredTelemetryButtonGroup_2.Title = 'Select Required Telemetry';
            app.GyroscopeButton = uiradiobutton(app.SelectRequiredTelemetryButtonGroup_2);
            app.GyroscopeButton.Text = 'Gyroscope';
            app.GyroscopeButton.Value = true;
            app.AccelerationButton = uiradiobutton(app.SelectRequiredTelemetryButtonGroup_2);
            app.AccelerationButton.Text = 'Acceleration';
            app.TemperatureButton = uiradiobutton(app.SelectRequiredTelemetryButtonGroup_2);
            app.TemperatureButton.Text = 'Temperature';
            app.AllTelemetryButton = uiradiobutton(app.SelectRequiredTelemetryButtonGroup_2);
            app.AllTelemetryButton.Text = 'All Telemetry';
            app.COMMANDButton_2 = uibutton(app.Telemetry, 'push');
            app.COMMANDButton_2.ButtonPushedFcn = createCallbackFcn(app, @COMMANDButton_2Pushed2, true);
            app.COMMANDButton_2.Text = 'COMMAND';
            app.Gauge = uigauge(app.Telemetry, 'circular');
            app.Gauge.Limits = [0 360];
            app.GROUNDSTATIONLabel = uilabel(app.GroundStationAeroSpaceTeam17UIFigure);
            app.GROUNDSTATIONLabel.Text = 'GROUND STATION ';
            app.PressF1toseehelpLabel = uilabel(app.GroundStationAeroSpaceTeam17UIFigure);
            app.PressF1toseehelpLabel.Text = 'Press F1 to see help';
            app.Switch = uiswitch(app.GroundStationAeroSpaceTeam17UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Image = uiimage(app.GroundStationAeroSpaceTeam17UIFigure);
            app.Image.ImageSource = 'Untitled-1.gif';
            % Show the figure after all components are created
            app.GroundStationAeroSpaceTeam17UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GroundStationAeroSpaceTeam17UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GroundStationAeroSpaceTeam17UIFigure)
        end
    end
end