classdef app7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GroundStationAeroSpaceTeam17UIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Rotation                        matlab.ui.container.Tab
        SelectDirectionDropDownLabel    matlab.ui.control.Label
        SelectDirectionDropDown         matlab.ui.control.DropDown
        StatusLabel                     matlab.ui.control.Label
        Data_Status                     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        COMMANDLabel                    matlab.ui.control.Label
        SpeedSpinnerLabel               matlab.ui.control.Label
        SpeedSpinner                    matlab.ui.control.Spinner
        SENDButton                      matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        AngleSpinnerLabel               matlab.ui.control.Label
        AngleSpinner                    matlab.ui.control.Spinner
        SpeedKnob                       matlab.ui.control.Knob
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        ClockwiseButton                 matlab.ui.control.RadioButton
        CounterClockwiseButton          matlab.ui.control.RadioButton
        AngleKnob                       matlab.ui.control.Knob
        Imagine                         matlab.ui.container.Tab
        OpenCameraButton                matlab.ui.control.Button
        CloseCameraButton               matlab.ui.control.Button
        Data_Status_2                   matlab.ui.control.Label
        StatusLabel_2                   matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        COMMANDLabel_2                  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        SaveButton                      matlab.ui.control.Button
        Telemetry                       matlab.ui.container.Tab
        XRotationLabel                  matlab.ui.control.Label
        YRotationLabel                  matlab.ui.control.Label
        ZRotationLabel                  matlab.ui.control.Label
        XAccelerationLabel              matlab.ui.control.Label
        YAccelerationLabel              matlab.ui.control.Label
        ZAccelerationLabel              matlab.ui.control.Label
        TemperatureCLabel               matlab.ui.control.Label
        GyroscopeTelemetryDegreeLabel   matlab.ui.control.Label
        AccelerationTelemetrymsLabel    matlab.ui.control.Label
        X_Rotation                      matlab.ui.control.Label
        Y_Rotation                      matlab.ui.control.Label
        Z_Rotation                      matlab.ui.control.Label
        X_Acceleration                  matlab.ui.control.Label
        Y_Acceleration                  matlab.ui.control.Label
        Z_Acceleration                  matlab.ui.control.Label
        Temperature                     matlab.ui.control.Label
        Lamp_3                          matlab.ui.control.Lamp
        StatusLabel_3                   matlab.ui.control.Label
        Data_Status_3                   matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        COMMANDLabel_3                  matlab.ui.control.Label
        SelectRequiredTelemetryButtonGroup_2  matlab.ui.container.ButtonGroup
        GyroscopeButton                 matlab.ui.control.RadioButton
        AccelerationButton              matlab.ui.control.RadioButton
        TemperatureButton               matlab.ui.control.RadioButton
        AllTelemetryButton              matlab.ui.control.RadioButton
        SENDButton_2                    matlab.ui.control.Button
        XRGauge                         matlab.ui.control.Gauge
        YRGauge                         matlab.ui.control.Gauge
        ZRGauge                         matlab.ui.control.Gauge
        TemperatureGauge                matlab.ui.control.LinearGauge
        XAGauge                         matlab.ui.control.Gauge
        YAGauge                         matlab.ui.control.Gauge
        ZAGauge                         matlab.ui.control.Gauge
        MonitorTab                      matlab.ui.container.Tab
        XRotationLabel_2                matlab.ui.control.Label
        YRotationLabel_2                matlab.ui.control.Label
        ZRotationLabel_2                matlab.ui.control.Label
        XAccelerationLabel_2            matlab.ui.control.Label
        YAccelerationLabel_2            matlab.ui.control.Label
        ZAccelerationLabel_2            matlab.ui.control.Label
        TemperatureCLabel_2             matlab.ui.control.Label
        GyroscopeTelemetryDegreeLabel_2  matlab.ui.control.Label
        AccelerationTelemetrymsLabel_2  matlab.ui.control.Label
        X_Rotation_2                    matlab.ui.control.Label
        Y_Rotation_2                    matlab.ui.control.Label
        Z_Rotation_2                    matlab.ui.control.Label
        X_Acceleration_2                matlab.ui.control.Label
        Y_Acceleration_2                matlab.ui.control.Label
        Z_Acceleration_2                matlab.ui.control.Label
        Temperature_2                   matlab.ui.control.Label
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes2_4                       matlab.ui.control.UIAxes
        UIAxes2_5                       matlab.ui.control.UIAxes
        UIAxes2_6                       matlab.ui.control.UIAxes
        ArtificialHorizon               Aero.ui.control.ArtificialHorizon
        UIAxes2_7                       matlab.ui.control.UIAxes
        EditField                       matlab.ui.control.NumericEditField
        GOLiveSwitch                    matlab.ui.control.Button
        Lamp_6                          matlab.ui.control.Lamp
        EditField_2                     matlab.ui.control.NumericEditField
        LOOPLabel                       matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        DelayLabel                      matlab.ui.control.Label
        GROUNDSTATIONLabel              matlab.ui.control.Label
        PressF1toseehelpLabel           matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_5                          matlab.ui.control.Lamp
        DropDown                        matlab.ui.control.DropDown
        Image_3                         matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = public)
        webcamObject;
        imageObject;
        
        % Change This To Your Paired COM (See Settings)
        % There is Another one at Switch .. Don't forget to change it.
        COM_Name = serial('COM5','BaudRate',9600);
        delay_Time ;
    end
    
    methods (Access = public)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            delete(instrfindall);
            clear all; clc;
        end

        % Button pushed function: SENDButton
        function SENDButtonPushed(app, event)
            % Select ClockwiseButton or CounterClockwiseButton
            app.Lamp.Color = 'red' ;
            Speed = app.SpeedSpinner.Value ;
            Speed = num2hex(single(Speed)) ;
            %             Angle = app.AngleKnob.Value ;
            %             Angle = num2hex(single(Angle)) ;
            
            if (app.ClockwiseButton.Value == 1)
                Write_This = join (['0x21 01 0A 00 04',Speed],' ');
                Send_This = {'21' '01' '0A' '00' '04', Speed(1:2) ,Speed(3:4),Speed(5:6),Speed(7:8)};
            elseif (app.CounterClockwiseButton.Value == 1)
                Write_This = join (['0x21 01 0B 00 04',Speed],' ');
                Send_This = {'21' '01' '0B' '00' '04', Speed(1:2) ,Speed(3:4),Speed(5:6),Speed(7:8)};
            end
            
            app.Label.Text = Write_This ;
            
            if app.Switch.Value ~= "On"
                msgbox('Please , Connect First ','Error','error')
            else
                fwrite(app.COM_Name,hex2dec(Send_This)) %Send Your Data.
                Motor = fread(app.COM_Name,1);
                app.Lamp.Color = 'green' ;
                app.Data_Status.Text = join (['Successfully Applied at ',string(datetime('now'))]);
            end
            
        end

        % Button pushed function: OpenCameraButton
        function OpenCameraButtonPushed(app, event)
            app.Lamp_2.Color ='red';
            app.Data_Status_2.Text = '';
            Write_This = '0x21 02 0A 00 00';
            Send_This = {'21' '02' '0A' '00' '00'} ;
            
            app.Label_2.Text = Write_This;
            
            if app.Switch.Value ~= "On"
                msgbox('Please , Connect First','Error','error')
            else
                
                fwrite(app.COM_Name,hex2dec(Send_This))
                Cam = fread(app.COM_Name,1);
                
                app.webcamObject = webcam('Integrated Webcam');
                app.imageObject = image(app.UIAxes);
                axis(app.UIAxes,'ij')
                res = split(app.webcamObject.Resolution,'x');
                app.UIAxes.XLim = [0,str2double(res{1})];
                app.UIAxes.YLim = [0,str2double(res{2})];
                app.webcamObject.preview(app.imageObject);
                
                app.Lamp_2.Color ='green';
                app.Data_Status_2.Text = join (['Successfully Applied at ',string(datetime('now'))]);
            end
            
        end

        % Button pushed function: CloseCameraButton
        function CloseCameraButtonPushed(app, event)
            
            app.Lamp_2.Color ='red';
            app.Data_Status_2.Text = '';
            
            Write_This = '0x21 02 0B 00 00';
            Send_This = {'21' '02' '0B' '00' '00'} ;
            
            app.Label_2.Text = Write_This;
            
            if app.Switch.Value ~= "On"
                msgbox('Please , Connect First ','Error','error')
            else
                fwrite(app.COM_Name,hex2dec(Send_This))
                Cam = fread(app.COM_Name,1);
                %  closePreview(webcam('Integrated Webcam'))
                %  clear(app.imageObject);
                %  clear('Integrated Webcam')
                app.Lamp_2.Color ='green';
                app.Data_Status_2.Text = join (['Successfully Applied at ',string(datetime('now'))]);
            end
            
        end

        % Value changed function: SpeedKnob
        function SpeedKnobValueChanged(app, event)
            
            app.SpeedSpinner.Value = app.SpeedKnob.Value ;
            
            if app.SpeedKnob.Value ~= 0 || app.SpeedSpinner.Value ~= 0
                app.AngleKnob.Value = 0;
                app.AngleSpinner.Value = 0;
            end
            
        end

        % Value changed function: SpeedSpinner
        function SpeedSpinnerValueChanged(app, event)
            
            if app.SpeedSpinner.Value < 0
                app.CounterClockwiseButton.Value = 1;
                app.SpeedSpinner.Value = abs(app.SpeedSpinner.Value) ;
            end
            
            if app.SpeedSpinner.Value > 100
                app.SpeedSpinner.Value = 100 ;
            end
            
            app.SpeedKnob.Value = app.SpeedSpinner.Value;
            
            if app.SpeedKnob.Value ~= 0 || app.SpeedSpinner.Value ~= 0
                app.AngleKnob.Value = 0;
                app.AngleSpinner.Value = 0;
            end
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            %    selectedTab = app.TabGroup.SelectedTab;
            % Clear all Labels for new COM_Nameands
            app.Label.Text   = '' ;
            app.Label_2.Text = '' ;
            app.Label_3.Text = '' ;
            app.SpeedSpinner.Value = 0;
            app.SpeedKnob.Value = 0;
            app.AngleSpinner.Value = 0;
            app.AngleKnob.Value=0;
            app.X_Rotation.Text = '' ;
            app.Y_Rotation.Text = '' ;
            app.Z_Rotation.Text = '' ;
            app.X_Acceleration.Text = '' ;
            app.Y_Acceleration.Text = '' ;
            app.Z_Acceleration.Text = '' ;
            app.Temperature.Text = '' ;
            app.Data_Status.Text = '' ;
            app.Data_Status_2.Text = '' ;
            app.Data_Status_3.Text = '' ;
            app.Lamp.Color='white';
            app.Lamp_2.Color='white';
            app.Lamp_3.Color='white';
        end

        % Value changed function: AngleKnob
        function AngleKnobValueChanged(app, event)
            
            app.AngleSpinner.Value = app.AngleKnob.Value;
            
            if app.AngleKnob.Value ~=0 || app.AngleSpinner.Value ~= 0
                app.SpeedKnob.Value = 0;
                app.SpeedSpinner.Value = 0;
            end
            
        end

        % Value changed function: AngleSpinner
        function AngleSpinnerValueChanged(app, event)
            
            app.AngleKnob.Value = app.AngleSpinner.Value;
            
            if app.AngleKnob.Value ~=0 || app.AngleSpinner.Value ~= 0
                app.SpeedKnob.Value = 0;
                app.SpeedSpinner.Value = 0;
            end
            
        end

        % Key press function: GroundStationAeroSpaceTeam17UIFigure
        function GroundStationAeroSpaceTeam17UIFigureKeyPress(app, event)
            key = event.Key;
            if key == "f1"
                msgbox("This Is The Ground Station For The ADCS subsystem Created by Aerospace Team","Help") ;
            end
        end

        % Button pushed function: SENDButton_2
        function SENDButton_2Pushed2(app, event)
            
            app.Lamp_3.Color = 'red';
            app.Data_Status_3.Text = '';
            
            if (app.GyroscopeButton.Value == 1)
                Write_This = '0x21 03 0A 0C 00';
                Send_This = {'21' '03' '0A' '0C' '00'} ;
            elseif (app.AccelerationButton.Value == 1)
                Write_This = '0x21 03 0B 0C 04';
                Send_This = {'21' '03' '0B' '0C' '00'} ;
            elseif (app.TemperatureButton.Value == 1)
                Write_This = '0x21 03 0C 04 00';
                Send_This = {'21' '03' '0C' '04' '00'} ;
            elseif (app.AllTelemetryButton.Value == 1)
                Write_This = '0x21 03 0D 1C 00';
                Send_This = {'21' '03' '0D' '1C' '00'} ;
            end
            
            % Packing The codes for user inputs
            
            app.Label_3.Text = Write_This;
            
            if app.Switch.Value ~= "On"
                msgbox('Please , Connect First','Error','error')
            else
                fwrite(app.COM_Name,hex2dec(Send_This)) %Send Your Data.
                
                
                if (app.GyroscopeButton.Value == 1)
                    
                    Gyro_rec=fread(app.COM_Name,13) ;
                    Gyro=dec2hex(Gyro_rec) ;
                    
                    Gyro_X=join([Gyro(5,:),Gyro(4,:),Gyro(3,:),Gyro(2,:)]);
                    GY_X=typecast(uint32(hex2dec(Gyro_X)),'single');
                    
                    Gyro_Y=join([Gyro(9,:),Gyro(8,:),Gyro(7,:),Gyro(6,:)]);
                    GY_Y=typecast(uint32(hex2dec(Gyro_Y)),'single');
                    
                    Gyro_Z=join([Gyro(13,:),Gyro(12,:),Gyro(11,:),Gyro(10,:)]);
                    GY_Z=typecast(uint32(hex2dec(Gyro_Z)),'single');
                    
                    app.X_Rotation.Text = sprintf('%.5f',GY_X);
                    app.Y_Rotation.Text = sprintf('%.5f',GY_Y);
                    app.Z_Rotation.Text = sprintf('%.5f',GY_Z);
                    
                    app.XRGauge.Value = GY_X ;
                    app.YRGauge.Value = GY_Y ;
                    app.ZRGauge.Value = GY_Z ;
                    
                    app.Lamp_3.Color = 'green';
                    app.Data_Status_3.Text = join (['Successfully Applied at ',string(datetime('now'))]);
                    
                elseif (app.AccelerationButton.Value == 1)
                    
                    Gyro_acc_rec=fread(app.COM_Name,13) ;
                    Gyro_acc=dec2hex(Gyro_acc_rec) ;
                    
                    Gyro_acc_X=join([Gyro_acc(5,:),Gyro_acc(4,:),Gyro_acc(3,:),Gyro_acc(2,:)]);
                    GY_acc_X=typecast(uint32(hex2dec(Gyro_acc_X)),'single');
                    
                    Gyro_acc_Y=join([Gyro_acc(9,:),Gyro_acc(8,:),Gyro_acc(7,:),Gyro_acc(6,:)]);
                    GY_acc_Y=typecast(uint32(hex2dec(Gyro_acc_Y)),'single');
                    
                    Gyro_acc_Z=join([Gyro_acc(13,:),Gyro_acc(12,:),Gyro_acc(11,:),Gyro_acc(10,:)]);
                    GY_acc_Z=typecast(uint32(hex2dec(Gyro_acc_Z)),'single');
                    
                    app.X_Acceleration.Text = sprintf('%.5f',GY_acc_X);
                    app.Y_Acceleration.Text = sprintf('%.5f',GY_acc_Y);
                    app.Z_Acceleration.Text = sprintf('%.5f',GY_acc_Z);
                    
                    app.XAGauge.Value = GY_acc_X ;
                    app.YAGauge.Value = GY_acc_Y ;
                    app.ZAGauge.Value = GY_acc_Z ;
                    
                    app.Lamp_3.Color = 'green';
                    app.Data_Status_3.Text = join (['Successfully Applied at ',string(datetime('now'))]);
                    
                elseif (app.TemperatureButton.Value == 1)
                    
                    Temperature_rec = fread(app.COM_Name,5) ;
                    
                    Temperature_rec=dec2hex(Temperature_rec);
                    Temperature_rec=join([Temperature_rec(5,:),Temperature_rec(4,:),Temperature_rec(3,:),Temperature_rec(2,:)]);
                    Temperature_rec=typecast(uint32(hex2dec(Temperature_rec)),'single');
                    
                    app.TemperatureGauge.Value = Temperature_rec;
                    app.Temperature.Text = sprintf('%.3f',Temperature_rec);
                    
                    app.Lamp_3.Color = 'green';
                    app.Data_Status_3.Text = join (['Successfully Applied at ',string(datetime('now'))]);
                    
                elseif (app.AllTelemetryButton.Value == 1)
                    
                    All_TLM =fread(app.COM_Name,29);
                    All_TLM=dec2hex(All_TLM) ;
                    
                    Gyro_X=join([All_TLM(5,:),All_TLM(4,:),All_TLM(3,:),All_TLM(2,:)]);
                    GY_X=typecast(uint32(hex2dec(Gyro_X)),'single');
                    
                    Gyro_Y=join([All_TLM(9,:),All_TLM(8,:),All_TLM(7,:),All_TLM(6,:)]);
                    GY_Y=typecast(uint32(hex2dec(Gyro_Y)),'single');
                    
                    Gyro_Z=join([All_TLM(13,:),All_TLM(12,:),All_TLM(11,:),All_TLM(10,:)]);
                    GY_Z=typecast(uint32(hex2dec(Gyro_Z)),'single');
                    
                    app.X_Rotation.Text = sprintf('%.5f',GY_X);
                    app.Y_Rotation.Text = sprintf('%.5f',GY_Y);
                    app.Z_Rotation.Text = sprintf('%.5f',GY_Z);
                    
                    app.XRGauge.Value = GY_Z ;
                    
                    Gyro_acc_X=join([All_TLM(17,:),All_TLM(16,:),All_TLM(15,:),All_TLM(14,:)]);
                    GY_acc_X=typecast(uint32(hex2dec(Gyro_acc_X)),'single');
                    
                    Gyro_acc_Y=join([All_TLM(21,:),All_TLM(20,:),All_TLM(19,:),All_TLM(18,:)]);
                    GY_acc_Y=typecast(uint32(hex2dec(Gyro_acc_Y)),'single');
                    
                    Gyro_acc_Z=join([All_TLM(25,:),All_TLM(24,:),All_TLM(23,:),All_TLM(22,:)]);
                    GY_acc_Z=typecast(uint32(hex2dec(Gyro_acc_Z)),'single');
                    
                    app.X_Acceleration.Text = sprintf('%.5f',GY_acc_X);
                    app.Y_Acceleration.Text = sprintf('%.5f',GY_acc_Y);
                    app.Z_Acceleration.Text = sprintf('%.5f',GY_acc_Z);
                    
                    Temperature_rec=join([All_TLM(29,:),All_TLM(28,:),All_TLM(27,:),All_TLM(26,:)]);
                    Temperature_rec=typecast(uint32(hex2dec(Temperature_rec)),'single');
                    
                    app.TemperatureGauge.Value = Temperature_rec;
                    app.Temperature.Text = sprintf('%.3f',Temperature_rec);
                    
                    app.Lamp_3.Color = 'green';
                    app.Data_Status_3.Text = join (['Successfully Applied at ',string(datetime('now'))]);
                    
                    app.XRGauge.Value = GY_X ;
                    app.YRGauge.Value = GY_Y ;
                    app.ZRGauge.Value = GY_Z ;
                    app.XAGauge.Value = GY_acc_X ;
                    app.YAGauge.Value = GY_acc_Y ;
                    app.ZAGauge.Value = GY_acc_Z ;
                    app.TemperatureGauge.Value = Temperature_rec;
                    
                    
                end
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            
            if app.Switch.Value == "On"
                
                app.COM_Name = serial('COM5','BaudRate',9600);
                % insert your serial properties here
                
                % WAIT BAR
                f = waitbar(0,'Please wait ...');
                fopen(app.COM_Name); %Open Port
                waitbar(.33,f,'Connecting to Bluetooth Device');
                pause(0.5)
                waitbar(.67,f,'Establishing Connection');
                pause(0.5)
                waitbar(1,f,'Finishing');
                pause(0.5)
                close(f)
                % fwrite(app.COM_Name,'Connected')
                msgbox('Connected')
                app.Lamp_4.Visible = 'on';
                app.Lamp_5.Visible = 'off';
                
            elseif app.Switch.Value == "Off"
                
                % fwrite(app.COM_Name,'DisConnected')
                delete(instrfindall);
                msgbox('Disconnected')
                app.Lamp_4.Visible = 'off';
                app.Lamp_5.Visible = 'on';
                
            end
        end

        % Value changed function: SelectDirectionDropDown
        function SelectDirectionDropDownValueChanged(app, event)
            
            if app.SelectDirectionDropDown.Value == "X-Rotation" || app.SelectDirectionDropDown.Value =="Y-Rotation"
                msgbox('NOT Designed yet 😄','Error','error')
                
                app.SENDButton.Visible = false;
            else
                app.SENDButton.Visible = true;
            end
            
        end

        % Button pushed function: GOLiveSwitch
        function GOLiveSwitchValueChanged(app, event)
            
                folder = 'E:\ESA';
%                 if ~exist(folder, 'dir')
%                     mkdir(folder);
%                 end
%                 recycle on % Send to recycle bin instead of permanently deleting.
%                 delete('Save_Data.xlsx')
                ExcelFileName = 'Save_Data.xlsx';
                fullFileName = fullfile(folder, ExcelFileName);
                
                if app.Switch.Value ~= "On"
                    msgbox('Please , Connect First','Error','error')
                    
                else
                    Send_This = {'21' '03' '0D' '1C' '00'} ;
                    
                    ii = 1 ;
                    
                    while(1)

%                         GOLiveSwitchValueChanged(app,event)
                        app.Lamp_6.Color ='yellow';
                        fwrite(app.COM_Name,hex2dec(Send_This)) %Send Your Data.
                        
                        All_TLM =fread(app.COM_Name,29);
                        All_TLM=dec2hex(All_TLM) ;
                        Gyro_X=join([All_TLM(5,:),All_TLM(4,:),All_TLM(3,:),All_TLM(2,:)]);
                        GY_X(ii)=typecast(uint32(hex2dec(Gyro_X)),'single');
                        
                        Gyro_Y=join([All_TLM(9,:),All_TLM(8,:),All_TLM(7,:),All_TLM(6,:)]);
                        GY_Y(ii)=typecast(uint32(hex2dec(Gyro_Y)),'single');
                        
                        Gyro_Z=join([All_TLM(13,:),All_TLM(12,:),All_TLM(11,:),All_TLM(10,:)]);
                        GY_Z(ii)=typecast(uint32(hex2dec(Gyro_Z)),'single');
                        
                        app.X_Rotation_2.Text = sprintf('%.5f',GY_X(ii));
                        app.Y_Rotation_2.Text = sprintf('%.5f',GY_Y(ii));
                        app.Z_Rotation_2.Text = sprintf('%.5f',GY_Z(ii));
                        
                        app.ArtificialHorizon.Value = [GY_X(ii),GY_Y(ii)];
                        
                        
                        Gyro_acc_X=join([All_TLM(17,:),All_TLM(16,:),All_TLM(15,:),All_TLM(14,:)]);
                        GY_acc_X(ii)=typecast(uint32(hex2dec(Gyro_acc_X)),'single');
                        
                        Gyro_acc_Y=join([All_TLM(21,:),All_TLM(20,:),All_TLM(19,:),All_TLM(18,:)]);
                        GY_acc_Y(ii)=typecast(uint32(hex2dec(Gyro_acc_Y)),'single');
                        
                        Gyro_acc_Z=join([All_TLM(25,:),All_TLM(24,:),All_TLM(23,:),All_TLM(22,:)]);
                        GY_acc_Z(ii)=typecast(uint32(hex2dec(Gyro_acc_Z)),'single');
                        
                        app.X_Acceleration_2.Text = sprintf('%.5f',GY_acc_X(ii));
                        app.Y_Acceleration_2.Text = sprintf('%.5f',GY_acc_Y(ii));
                        app.Z_Acceleration_2.Text = sprintf('%.5f',GY_acc_Z(ii));
                        
                        Temperature_rec = join([All_TLM(29,:),All_TLM(28,:),All_TLM(27,:),All_TLM(26,:)]);
                        Temperature_rec = typecast(uint32(hex2dec(Temperature_rec)),'single');
                        
                        app.Temperature_2.Text = sprintf('%.3f',Temperature_rec);
                        Temp_vec(ii) = Temperature_rec ;
                        
                        
                        plot(app.UIAxes2  ,GY_X,'-b');
                        plot(app.UIAxes2_2,GY_Y,'-r');
                        plot(app.UIAxes2_3,GY_Z,'-k');
                        
                        plot(app.UIAxes2_4  ,GY_acc_X,'-b');
                        plot(app.UIAxes2_5,GY_acc_Y,'-r');
                        plot(app.UIAxes2_6,GY_acc_Z,'-k');
                        
                        plot(app.UIAxes2_7,Temp_vec,'-g');
                        

                        ii = ii + 1 ;
                        
                        pause(app.delay_Time)
                        
                        if ii> app.EditField.Value
                            break
                        end
                    end
                    app.Lamp_6.Color ='red';
                    
                    xlswrite(fullFileName,GY_X(:),1,join(['A' ,int2str(2)]));
                    xlswrite(fullFileName,GY_Y(:),1,join(['B' ,int2str(2)]));
                    xlswrite(fullFileName,GY_Z(:),1,join(['C' ,int2str(2)]));
                    xlswrite(fullFileName,GY_acc_X(:),1,join(['D' ,int2str(2)]));
                    xlswrite(fullFileName,GY_acc_Y(:),1,join(['E' ,int2str(2)]));
                    xlswrite(fullFileName,GY_acc_Z(:),1,join(['F' ,int2str(2)]));
                    xlswrite(fullFileName,Temp_vec(:),1,join(['G' ,int2str(2)]));
                    
                end


        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.delay_Time = app.EditField_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GroundStationAeroSpaceTeam17UIFigure = uifigure('Visible', 'off');
            app.GroundStationAeroSpaceTeam17UIFigure.Name = 'Ground Station - AeroSpace Team 17';
            app.GroundStationAeroSpaceTeam17UIFigure.KeyPressFcn = createCallbackFcn(app, @GroundStationAeroSpaceTeam17UIFigureKeyPress, true);
            app.TabGroup = uitabgroup(app.GroundStationAeroSpaceTeam17UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Rotation = uitab(app.TabGroup);
            app.Rotation.Title = 'Rotation';
            app.SelectDirectionDropDownLabel = uilabel(app.Rotation);
            app.SelectDirectionDropDownLabel.Text = {'Select Direction'; ''};
            app.SelectDirectionDropDown = uidropdown(app.Rotation);
            app.SelectDirectionDropDown.Items = {'X-Rotation', 'Y-Rotation', 'Z-Rotation'};
            app.SelectDirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDirectionDropDownValueChanged, true);
            app.SelectDirectionDropDown.Value = 'Z-Rotation';
            app.StatusLabel = uilabel(app.Rotation);
            app.StatusLabel.Text = 'Status';
            app.Data_Status = uilabel(app.Rotation);
            app.Data_Status.Text = '';
            app.Label = uilabel(app.Rotation);
            app.Label.Text = '';
            app.COMMANDLabel = uilabel(app.Rotation);
            app.COMMANDLabel.Text = 'COMMAND';
            app.SpeedSpinnerLabel = uilabel(app.Rotation);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.Rotation);
            app.SpeedSpinner.Limits = [0 100];
            app.SpeedSpinner.ValueChangedFcn = createCallbackFcn(app, @SpeedSpinnerValueChanged, true);
            app.SENDButton = uibutton(app.Rotation, 'push');
            app.SENDButton.ButtonPushedFcn = createCallbackFcn(app, @SENDButtonPushed, true);
            app.SENDButton.Text = 'SEND';
            app.Lamp = uilamp(app.Rotation);
            app.AngleSpinnerLabel = uilabel(app.Rotation);
            app.AngleSpinnerLabel.Text = 'Angle';
            app.AngleSpinner = uispinner(app.Rotation);
            app.AngleSpinner.Limits = [0 360];
            app.AngleSpinner.ValueChangedFcn = createCallbackFcn(app, @AngleSpinnerValueChanged, true);
            app.SpeedKnob = uiknob(app.Rotation, 'continuous');
            app.SpeedKnob.MajorTicks = [0 10 20 30 40 50 60 70 80 90];
            app.SpeedKnob.ValueChangedFcn = createCallbackFcn(app, @SpeedKnobValueChanged, true);
            app.ButtonGroup_2 = uibuttongroup(app.Rotation);
            app.ClockwiseButton = uiradiobutton(app.ButtonGroup_2);
            app.ClockwiseButton.Text = 'Clockwise';
            app.ClockwiseButton.Value = true;
            app.CounterClockwiseButton = uiradiobutton(app.ButtonGroup_2);
            app.CounterClockwiseButton.Text = 'Counter-Clockwise';
            app.AngleKnob = uiknob(app.Rotation, 'continuous');
            app.AngleKnob.Limits = [0 360];
            app.AngleKnob.MajorTicks = [0 30 60 90 120 150 180 210 240 270 300 330 360];
            app.AngleKnob.ValueChangedFcn = createCallbackFcn(app, @AngleKnobValueChanged, true);
            app.Imagine = uitab(app.TabGroup);
            app.Imagine.Title = 'Imaging';
            app.OpenCameraButton = uibutton(app.Imagine, 'push');
            app.OpenCameraButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCameraButtonPushed, true);
            app.OpenCameraButton.Text = 'Open Camera';
            app.CloseCameraButton = uibutton(app.Imagine, 'push');
            app.CloseCameraButton.ButtonPushedFcn = createCallbackFcn(app, @CloseCameraButtonPushed, true);
            app.CloseCameraButton.Text = 'Close Camera';
            app.Data_Status_2 = uilabel(app.Imagine);
            app.Data_Status_2.Text = '';
            app.StatusLabel_2 = uilabel(app.Imagine);
            app.StatusLabel_2.Text = 'Status';
            app.UIAxes = uiaxes(app.Imagine);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            app.COMMANDLabel_2 = uilabel(app.Imagine);
            app.COMMANDLabel_2.Text = 'COMMAND';
            app.Label_2 = uilabel(app.Imagine);
            app.Label_2.Text = '';
            app.Lamp_2 = uilamp(app.Imagine);
            app.SaveButton = uibutton(app.Imagine, 'push');
            app.SaveButton.Text = 'Save';
            app.Telemetry = uitab(app.TabGroup);
            app.Telemetry.Title = 'Telemetry';
            app.XRotationLabel = uilabel(app.Telemetry);
            app.XRotationLabel.Text = 'X-Rotation:';
            app.YRotationLabel = uilabel(app.Telemetry);
            app.YRotationLabel.Text = 'Y-Rotation:';
            app.ZRotationLabel = uilabel(app.Telemetry);
            app.ZRotationLabel.Text = 'Z-Rotation:';
            app.XAccelerationLabel = uilabel(app.Telemetry);
            app.XAccelerationLabel.Text = 'X-Acceleration:';
            app.YAccelerationLabel = uilabel(app.Telemetry);
            app.YAccelerationLabel.Text = 'Y-Acceleration:';
            app.ZAccelerationLabel = uilabel(app.Telemetry);
            app.ZAccelerationLabel.Text = 'Z-Acceleration:';
            app.TemperatureCLabel = uilabel(app.Telemetry);
            app.TemperatureCLabel.Text = 'Temperature (°C):';
            app.GyroscopeTelemetryDegreeLabel = uilabel(app.Telemetry);
            app.GyroscopeTelemetryDegreeLabel.Text = 'Gyroscope Telemetry (Degree)';
            app.AccelerationTelemetrymsLabel = uilabel(app.Telemetry);
            app.AccelerationTelemetrymsLabel.Text = 'Acceleration Telemetry (m/s)';
            app.X_Rotation = uilabel(app.Telemetry);
            app.X_Rotation.Text = '';
            app.Y_Rotation = uilabel(app.Telemetry);
            app.Y_Rotation.Text = '';
            app.Z_Rotation = uilabel(app.Telemetry);
            app.Z_Rotation.Text = '';
            app.X_Acceleration = uilabel(app.Telemetry);
            app.X_Acceleration.Text = '';
            app.Y_Acceleration = uilabel(app.Telemetry);
            app.Y_Acceleration.Text = '';
            app.Z_Acceleration = uilabel(app.Telemetry);
            app.Z_Acceleration.Text = '';
            app.Temperature = uilabel(app.Telemetry);
            app.Temperature.Text = '';
            app.Lamp_3 = uilamp(app.Telemetry);
            app.StatusLabel_3 = uilabel(app.Telemetry);
            app.StatusLabel_3.Text = 'Status';
            app.Data_Status_3 = uilabel(app.Telemetry);
            app.Data_Status_3.Text = '';
            app.Label_3 = uilabel(app.Telemetry);
            app.Label_3.Text = '';
            app.COMMANDLabel_3 = uilabel(app.Telemetry);
            app.COMMANDLabel_3.Text = 'COMMAND';
            app.SelectRequiredTelemetryButtonGroup_2 = uibuttongroup(app.Telemetry);
            app.SelectRequiredTelemetryButtonGroup_2.Title = 'Select Required Telemetry';
            app.GyroscopeButton = uiradiobutton(app.SelectRequiredTelemetryButtonGroup_2);
            app.GyroscopeButton.Text = 'Gyroscope';
            app.GyroscopeButton.Value = true;
            app.AccelerationButton = uiradiobutton(app.SelectRequiredTelemetryButtonGroup_2);
            app.AccelerationButton.Text = 'Acceleration';
            app.TemperatureButton = uiradiobutton(app.SelectRequiredTelemetryButtonGroup_2);
            app.TemperatureButton.Text = 'Temperature';
            app.AllTelemetryButton = uiradiobutton(app.SelectRequiredTelemetryButtonGroup_2);
            app.AllTelemetryButton.Text = 'All Telemetry';
            app.SENDButton_2 = uibutton(app.SelectRequiredTelemetryButtonGroup_2, 'push');
            app.SENDButton_2.ButtonPushedFcn = createCallbackFcn(app, @SENDButton_2Pushed2, true);
            app.SENDButton_2.Text = 'SEND';
            app.XRGauge = uigauge(app.Telemetry, 'circular');
            app.XRGauge.Limits = [-180 180];
            app.YRGauge = uigauge(app.Telemetry, 'circular');
            app.YRGauge.Limits = [-180 180];
            app.ZRGauge = uigauge(app.Telemetry, 'circular');
            app.ZRGauge.Limits = [-180 180];
            app.TemperatureGauge = uigauge(app.Telemetry, 'linear');
            app.TemperatureGauge.Limits = [0 50];
            app.TemperatureGauge.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50];
            app.XAGauge = uigauge(app.Telemetry, 'circular');
            app.XAGauge.Limits = [-30 30];
            app.YAGauge = uigauge(app.Telemetry, 'circular');
            app.YAGauge.Limits = [-30 30];
            app.ZAGauge = uigauge(app.Telemetry, 'circular');
            app.ZAGauge.Limits = [-30 30];
            app.MonitorTab = uitab(app.TabGroup);
            app.MonitorTab.Title = 'Monitor';
            app.XRotationLabel_2 = uilabel(app.MonitorTab);
            app.XRotationLabel_2.Text = 'X-Rotation:';
            app.YRotationLabel_2 = uilabel(app.MonitorTab);
            app.YRotationLabel_2.Text = 'Y-Rotation:';
            app.ZRotationLabel_2 = uilabel(app.MonitorTab);
            app.ZRotationLabel_2.Text = 'Z-Rotation:';
            app.XAccelerationLabel_2 = uilabel(app.MonitorTab);
            app.XAccelerationLabel_2.Text = 'X-Acceleration:';
            app.YAccelerationLabel_2 = uilabel(app.MonitorTab);
            app.YAccelerationLabel_2.Text = 'Y-Acceleration:';
            app.ZAccelerationLabel_2 = uilabel(app.MonitorTab);
            app.ZAccelerationLabel_2.Text = 'Z-Acceleration:';
            app.TemperatureCLabel_2 = uilabel(app.MonitorTab);
            app.TemperatureCLabel_2.Text = 'Temperature (°C):';
            app.GyroscopeTelemetryDegreeLabel_2 = uilabel(app.MonitorTab);
            app.GyroscopeTelemetryDegreeLabel_2.Text = 'Gyroscope Telemetry (Degree)';
            app.AccelerationTelemetrymsLabel_2 = uilabel(app.MonitorTab);
            app.AccelerationTelemetrymsLabel_2.Text = 'Acceleration Telemetry (m/s)';
            app.X_Rotation_2 = uilabel(app.MonitorTab);
            app.X_Rotation_2.Text = '';
            app.Y_Rotation_2 = uilabel(app.MonitorTab);
            app.Y_Rotation_2.Text = '';
            app.Z_Rotation_2 = uilabel(app.MonitorTab);
            app.Z_Rotation_2.Text = '';
            app.X_Acceleration_2 = uilabel(app.MonitorTab);
            app.X_Acceleration_2.Text = '';
            app.Y_Acceleration_2 = uilabel(app.MonitorTab);
            app.Y_Acceleration_2.Text = '';
            app.Z_Acceleration_2 = uilabel(app.MonitorTab);
            app.Z_Acceleration_2.Text = '';
            app.Temperature_2 = uilabel(app.MonitorTab);
            app.Temperature_2.Text = '';
            app.UIAxes2_2 = uiaxes(app.MonitorTab);
            title(app.UIAxes2_2, '')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, 'Y-Rotation')
            app.UIAxes2_3 = uiaxes(app.MonitorTab);
            title(app.UIAxes2_3, '')
            xlabel(app.UIAxes2_3, '')
            ylabel(app.UIAxes2_3, 'Z-Rotation')
            app.UIAxes2_4 = uiaxes(app.MonitorTab);
            title(app.UIAxes2_4, '')
            xlabel(app.UIAxes2_4, '')
            ylabel(app.UIAxes2_4, 'X-Acceleration')
            app.UIAxes2_5 = uiaxes(app.MonitorTab);
            title(app.UIAxes2_5, '')
            xlabel(app.UIAxes2_5, '')
            ylabel(app.UIAxes2_5, 'Y-Acceleration')
            app.UIAxes2_6 = uiaxes(app.MonitorTab);
            title(app.UIAxes2_6, '')
            xlabel(app.UIAxes2_6, '')
            ylabel(app.UIAxes2_6, 'Z-Acceleration')
            app.ArtificialHorizon = uiaerohorizon(app.MonitorTab);
            app.UIAxes2_7 = uiaxes(app.MonitorTab);
            title(app.UIAxes2_7, 'Temperature')
            xlabel(app.UIAxes2_7, '')
            ylabel(app.UIAxes2_7, '')
            app.UIAxes2_7.YAxisLocation = 'right';
            app.EditField = uieditfield(app.MonitorTab, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.Value = 20;
            app.GOLiveSwitch = uibutton(app.MonitorTab, 'push');
            app.GOLiveSwitch.ButtonPushedFcn = createCallbackFcn(app, @GOLiveSwitchValueChanged, true);
            app.GOLiveSwitch.Text = 'GO LIVE';
            app.Lamp_6 = uilamp(app.MonitorTab);
            app.EditField_2 = uieditfield(app.MonitorTab, 'numeric');
            app.EditField_2.Limits = [0 1000];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 0.001;
            app.LOOPLabel = uilabel(app.MonitorTab);
            app.LOOPLabel.Text = 'LOOP';
            app.DelayLabel = uilabel(app.MonitorTab);
            app.DelayLabel.Text = 'Delay';
            app.UIAxes2 = uiaxes(app.MonitorTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, 'X-Rotation')
            app.GROUNDSTATIONLabel = uilabel(app.GroundStationAeroSpaceTeam17UIFigure);
            app.GROUNDSTATIONLabel.Text = 'GROUND STATION ';
            app.PressF1toseehelpLabel = uilabel(app.GroundStationAeroSpaceTeam17UIFigure);
            app.PressF1toseehelpLabel.Text = 'Press F1 to see help';
            app.Switch = uiswitch(app.GroundStationAeroSpaceTeam17UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp_4 = uilamp(app.GroundStationAeroSpaceTeam17UIFigure);
            app.Lamp_4.Visible = 'off';
            app.Lamp_5 = uilamp(app.GroundStationAeroSpaceTeam17UIFigure);
            app.DropDown = uidropdown(app.GroundStationAeroSpaceTeam17UIFigure);
            app.DropDown.Items = {'''COM1''', '''COM2''', '''COM3''', '''COM4''', '''COM5''', '''COM6''', '''COM7''', '''COM8''', '''COM9''', '''COM10''', '''COM11'''};
            app.DropDown.Value = '''COM5''';
            app.Image_3 = uiimage(app.GroundStationAeroSpaceTeam17UIFigure);
            app.Image_3.ImageSource = 'AeroSpace_Logo.gif';
            app.Image_4 = uiimage(app.GroundStationAeroSpaceTeam17UIFigure);
            app.Image_4.ImageSource = 'EGSA_Logo.png';
            app.Image = uiimage(app.GroundStationAeroSpaceTeam17UIFigure);
            app.Image.ImageSource = 'Cairo_Logo.gif';
            % Show the figure after all components are created
            app.GroundStationAeroSpaceTeam17UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GroundStationAeroSpaceTeam17UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GroundStationAeroSpaceTeam17UIFigure)
        end
    end
end